classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        WindowLengthInput    matlab.ui.control.Spinner
        Label_5              matlab.ui.control.Label
        Step3Label           matlab.ui.control.Label
        RunButton            matlab.ui.control.Button
        UsedComponentsInput  matlab.ui.control.EditField
        Label_4              matlab.ui.control.Label
        Step2Label           matlab.ui.control.Label
        Step1Label           matlab.ui.control.Label
        LoadFileButton       matlab.ui.control.Button
        OriginSeries         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        T % Отметки времени измерений сигнала
        F % Исходный сигнал
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [file, path] = uigetfile("*.csv", "Выберите csv-файл с данными");
            file_data = readtable(strcat(path, file));
            app.F = file_data.RCORR_P;
            app.T = file_data.start_date_time;
            app.OriginSeries.Visible = true;
            plot(app.OriginSeries, app.T, app.F, 'b-');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Инструмент обработки и анализа сигналов геофизического мониторинга';
            app.OriginSeries = uiaxes(app.UIFigure);
            title(app.OriginSeries, 'Исходный сигнал')
            app.OriginSeries.Visible = 'off';
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Загрузить файл';
            app.Step1Label = uilabel(app.UIFigure);
            app.Step1Label.Text = 'Шаг 1: Загрузите данные';
            app.Step2Label = uilabel(app.UIFigure);
            app.Step2Label.Text = 'Шаг 2: Укажите параметры метода';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Используемые компоненты';
            app.UsedComponentsInput = uieditfield(app.UIFigure, 'text');
            app.UsedComponentsInput.Placeholder = '1, 2, 3';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.Text = 'Начать обработку';
            app.Step3Label = uilabel(app.UIFigure);
            app.Step3Label.Text = 'Шаг3 : Запуск метода';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Длина окна';
            app.WindowLengthInput = uispinner(app.UIFigure);
            app.WindowLengthInput.Limits = [1 Inf];
            app.WindowLengthInput.Value = 1440;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end